classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        TempAxes                       matlab.ui.control.UIAxes
        CostAxes                       matlab.ui.control.UIAxes
        PowerAxes                      matlab.ui.control.UIAxes
        PropertiesPanel                matlab.ui.container.Panel
        RoomLabel                      matlab.ui.control.Label
        WindowsLabel                   matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        lengthEditFieldLabel           matlab.ui.control.Label
        RoomLength                     matlab.ui.control.NumericEditField
        widthEditFieldLabel            matlab.ui.control.Label
        RoomWidth                      matlab.ui.control.NumericEditField
        lengthEditField_2Label         matlab.ui.control.Label
        WallLength                     matlab.ui.control.NumericEditField
        widthEditField_2Label          matlab.ui.control.Label
        WallWidth                      matlab.ui.control.NumericEditField
        NumberofwindowsSpinnerLabel    matlab.ui.control.Label
        heightEditField_2Label         matlab.ui.control.Label
        WindowsHeight                  matlab.ui.control.NumericEditField
        heightEditFieldLabel           matlab.ui.control.Label
        RoomHeight                     matlab.ui.control.NumericEditField
        widthEditField_3Label          matlab.ui.control.Label
        WindowsWidth                   matlab.ui.control.NumericEditField
        WallmaterialDropDownLabel      matlab.ui.control.Label
        InsulationDropDownLabel        matlab.ui.control.Label
        Insulation                     matlab.ui.control.DropDown
        RoomAboveCheckBox              matlab.ui.control.CheckBox
        FloorSpinnerLabel              matlab.ui.control.Label
        Floor                          matlab.ui.control.Spinner
        WallMaterial                   matlab.ui.control.DropDown
        WindowsNum                     matlab.ui.control.Spinner
        WallCheckBox                   matlab.ui.control.CheckBox
        StartButton                    matlab.ui.control.Button
        OutdoortemperatureSliderLabel  matlab.ui.control.Label
        OutdoorTempSlider              matlab.ui.control.Slider
        PlotsPanel                     matlab.ui.container.Panel
        ShowplotLabel                  matlab.ui.control.Label
        TemperatureCheckBox            matlab.ui.control.CheckBox
        CostsCheckBox                  matlab.ui.control.CheckBox
        PowerCheckBox                  matlab.ui.control.CheckBox
        TimePanel                      matlab.ui.container.Panel
        DurationSliderLabel            matlab.ui.control.Label
        DurationSlider                 matlab.ui.control.Slider
        fromEditFieldLabel             matlab.ui.control.Label
        LowerLabel                     matlab.ui.control.Label
        toEditFieldLabel               matlab.ui.control.Label
        LowerTo                        matlab.ui.control.NumericEditField
        EverydayCheckBox               matlab.ui.control.CheckBox
        LowerFrom                      matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CostAxes.Visible = 'off';
            app.PowerAxes.Visible = 'off';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            assignin('base','init_temp', app.OutdoorTempSlider.Value);
            simout = sim('model','StopTime',num2str(app.DurationSlider.Value));
            app.CostAxes.Visible = 'on';
            app.PowerAxes.Visible = 'on';
            plot(app.TempAxes,simout.temp);
            hold(app.TempAxes);
            plot(app.TempAxes, simout.temp2);
            plot(app.CostAxes,simout.cost);
            plot(app.PowerAxes, simout.power);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.TempAxes = uiaxes(app.UIFigure);
            title(app.TempAxes, 'Temperature')
            xlabel(app.TempAxes, 'Time')
            ylabel(app.TempAxes, 'Temp')
            app.CostAxes = uiaxes(app.UIFigure);
            title(app.CostAxes, 'Cost')
            xlabel(app.CostAxes, 'Time')
            ylabel(app.CostAxes, 'Expense')
            app.PowerAxes = uiaxes(app.UIFigure);
            title(app.PowerAxes, 'Energy consumption')
            xlabel(app.PowerAxes, 'Time')
            ylabel(app.PowerAxes, 'Power')
            app.PropertiesPanel = uipanel(app.UIFigure);
            app.PropertiesPanel.Title = 'Properties';
            app.RoomLabel = uilabel(app.PropertiesPanel);
            app.RoomLabel.Text = 'Room dimensions';
            app.WindowsLabel = uilabel(app.PropertiesPanel);
            app.WindowsLabel.Text = 'Windows dimensions';
            app.Label = uilabel(app.PropertiesPanel);
            app.Label.Text = '';
            app.lengthEditFieldLabel = uilabel(app.PropertiesPanel);
            app.lengthEditFieldLabel.Text = 'length';
            app.RoomLength = uieditfield(app.PropertiesPanel, 'numeric');
            app.WallCheckBox = uicheckbox(app.PropertiesPanel);
            app.WallCheckBox.Text = 'Partition wall';
            app.widthEditFieldLabel = uilabel(app.PropertiesPanel);
            app.widthEditFieldLabel.Text = 'width';
            app.RoomWidth = uieditfield(app.PropertiesPanel, 'numeric');
            app.lengthEditField_2Label = uilabel(app.PropertiesPanel);
            app.lengthEditField_2Label.Text = 'length';
            app.WallLength = uieditfield(app.PropertiesPanel, 'numeric');
            app.widthEditField_2Label = uilabel(app.PropertiesPanel);
            app.widthEditField_2Label.Text = 'width';
            app.WallWidth = uieditfield(app.PropertiesPanel, 'numeric');
            app.NumberofwindowsSpinnerLabel = uilabel(app.PropertiesPanel);
            app.NumberofwindowsSpinnerLabel.Text = 'Number of windows';
            app.WindowsNum = uispinner(app.PropertiesPanel);
            app.heightEditField_2Label = uilabel(app.PropertiesPanel);
            app.heightEditField_2Label.Text = 'height';
            app.WindowsHeight = uieditfield(app.PropertiesPanel, 'numeric');
            app.heightEditFieldLabel = uilabel(app.PropertiesPanel);
            app.heightEditFieldLabel.Text = 'height';
            app.RoomHeight = uieditfield(app.PropertiesPanel, 'numeric');
            app.widthEditField_3Label = uilabel(app.PropertiesPanel);
            app.widthEditField_3Label.Text = 'width';
            app.WindowsWidth = uieditfield(app.PropertiesPanel, 'numeric');
            app.WallmaterialDropDownLabel = uilabel(app.PropertiesPanel);
            app.WallmaterialDropDownLabel.Text = 'Wall material';
            app.WallMaterial = uidropdown(app.PropertiesPanel);
            app.InsulationDropDownLabel = uilabel(app.PropertiesPanel);
            app.InsulationDropDownLabel.Text = 'Insulation';
            app.Insulation = uidropdown(app.PropertiesPanel);
            app.RoomAboveCheckBox = uicheckbox(app.PropertiesPanel);
            app.RoomAboveCheckBox.Text = 'There is a heated room above';
            app.FloorSpinnerLabel = uilabel(app.PropertiesPanel);
            app.FloorSpinnerLabel.Text = 'Floor';
            app.Floor = uispinner(app.PropertiesPanel);
            app.Floor.Limits = [0 20];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.OutdoortemperatureSliderLabel = uilabel(app.UIFigure);
            app.OutdoortemperatureSliderLabel.Text = {'Outdoor'; 'temperature'};
            app.OutdoorTempSlider = uislider(app.UIFigure);
            app.OutdoorTempSlider.Limits = [-30 15];
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.ShowplotLabel = uilabel(app.PlotsPanel);
            app.ShowplotLabel.Text = 'Show plot of:';
            app.TemperatureCheckBox = uicheckbox(app.PlotsPanel);
            app.TemperatureCheckBox.Text = 'Temperature';
            app.CostsCheckBox = uicheckbox(app.PlotsPanel);
            app.CostsCheckBox.Text = 'Costs';
            app.PowerCheckBox = uicheckbox(app.PlotsPanel);
            app.PowerCheckBox.Text = 'Power';
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'Time';
            app.DurationSliderLabel = uilabel(app.TimePanel);
            app.DurationSliderLabel.Text = 'Duration';
            app.DurationSlider = uislider(app.TimePanel);
            app.DurationSlider.Limits = [0 168];
            app.DurationSlider.MajorTicks = [0 24 48 72 96 120 144 168];
            app.DurationSlider.MinorTicks = [12 36 60 84 108 132 156];
            app.fromEditFieldLabel = uilabel(app.TimePanel);
            app.fromEditFieldLabel.Text = 'from';
            app.LowerFrom = uieditfield(app.TimePanel, 'numeric');
            app.LowerLabel = uilabel(app.TimePanel);
            app.LowerLabel.Text = 'Set lower temperature';
            app.toEditFieldLabel = uilabel(app.TimePanel);
            app.toEditFieldLabel.Text = 'to';
            app.LowerTo = uieditfield(app.TimePanel, 'numeric');
            app.EverydayCheckBox = uicheckbox(app.TimePanel);
            app.EverydayCheckBox.Text = 'Everyday';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end